classdef Beam_Deflection_Calculator_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResultsPanel                   matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        InputsPanel                    matlab.ui.container.Panel
        SaveResultsButton              matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        UDLIntensitywNmEditField       matlab.ui.control.NumericEditField
        UDLIntensitywNmLabel           matlab.ui.control.Label
        PointLoadPositionamEditField   matlab.ui.control.NumericEditField
        PointLoadPositionamLabel       matlab.ui.control.Label
        PointLoadPNEditField           matlab.ui.control.NumericEditField
        PointLoadPNLabel               matlab.ui.control.Label
        LoadTypeDropDown               matlab.ui.control.DropDown
        LoadTypeDropDownLabel          matlab.ui.control.Label
        SupportTypeDropDown            matlab.ui.control.DropDown
        SupportTypeDropDownLabel       matlab.ui.control.Label
        SecondMomentofAream4EditField  matlab.ui.control.NumericEditField
        SecondMomentofAream4Label      matlab.ui.control.Label
        YoungsModulusPaEditField       matlab.ui.control.NumericEditField
        YoungsModulusPaEditFieldLabel  matlab.ui.control.Label
        BeamLengthmEditField           matlab.ui.control.NumericEditField
        BeamLengthmLabel               matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%% --- Read input values from App ---
Beam.L       = app.BeamLengthmEditField.Value;          % Beam length [m]
Beam.E       = app.YoungsModulusPaEditField.Value;     % Young's Modulus [Pa]
Beam.I       = app.SecondMomentofAream4EditField.Value; % Moment of Inertia [m^4]
Beam.loadType = app.LoadTypeDropDown.Value;            % 'Point' or 'UDL'
Beam.support  = app.SupportTypeDropDown.Value;         % 'Cantilever', 'Simply', 'FixedFixed'

if strcmpi(Beam.loadType,'point')
    Beam.P = app.PointLoadPNEditField.Value;            % Point load [N]
    Beam.a = app.PointLoadPositionamEditField.Value;    % Load position [m]
else
    Beam.w = app.UDLIntensitywNmEditField.Value;        % UDL intensity [N/m]
end

%% --- Discretization ---
n = 200;                        % Number of points
x = linspace(0, Beam.L, n);     % Beam positions

% Initialize arrays
y = zeros(size(x));  % Deflection
M = zeros(size(x));  % Moment
V = zeros(size(x));  % Shear

%% --- Computational Analysis ---
switch lower(Beam.loadType)
    case 'point'
        switch lower(Beam.support)
            case 'cantilever'
                y = -(Beam.P*x.^2/(6*Beam.E*Beam.I)).*(3*Beam.a - x).*(x<=Beam.a) ...
                    - (Beam.P*Beam.a^2/(6*Beam.E*Beam.I)).*(3*x - Beam.a).*(x>Beam.a);
                M = -(Beam.P * (Beam.a - x)) .* (x <= Beam.a);
                V = -Beam.P * (x <= Beam.a);

            case 'simply'
                R1 = Beam.P*(Beam.L - Beam.a)/Beam.L;
                R2 = Beam.P*Beam.a/Beam.L;
                M = R1.*x - Beam.P.*max(0,x-Beam.a);
                V = R1 - Beam.P.*(x >= Beam.a);
                y = (R1 .* x .* (Beam.L.^2 - x.^2)/(6*Beam.E*Beam.I)) ...
                    - (Beam.P .* max(0, x-Beam.a).^3/(6*Beam.E*Beam.I));

            case 'fixedfixed'
                if abs(Beam.a - Beam.L/2) > 1e-6
                    uialert(app.UIFigure,'Off-center load for fixed-fixed not implemented','Error');
                    return;
                end
                y = -(Beam.P*x.*(Beam.L^3 - 2*Beam.L*x.^2 + x.^3))/(48*Beam.E*Beam.I);
                M = Beam.P*Beam.L/8 * (1 - 2*x/Beam.L);
                V = [repmat(Beam.P/2,1,ceil(n/2)) repmat(-Beam.P/2,1,floor(n/2))];
        end

    case 'udl'
        switch lower(Beam.support)
            case 'cantilever'
                y = -(Beam.w*x.^2/(24*Beam.E*Beam.I)).*(6*Beam.L^2 - 4*Beam.L*x + x.^2);
                M = -Beam.w/2 .* (Beam.L - x).^2;
                V = -Beam.w * (Beam.L - x);

            case 'simply'
                R = Beam.w*Beam.L/2;
                M = R.*x - (Beam.w.*x.^2)/2;
                V = R - Beam.w.*x;
                y = (Beam.w .* x .* (Beam.L^3 - 2*Beam.L*x.^2 + x.^3))/(24*Beam.E*Beam.I);

            case 'fixedfixed'
                y = -(Beam.w*x.*(Beam.L^3 - 2*Beam.L*x.^2 + x.^3))/(24*Beam.E*Beam.I);
                M = Beam.w*Beam.L^2/12 * (1 - 6*(x/Beam.L).*(1-x/Beam.L));
                V = gradient(M, x(2)-x(1));
        end
end

%% --- Compute maximum values ---
maxDeflection = min(y);                 % meters
maxMoment     = max(abs(M));            % N·m
maxShear      = max(abs(V));            % N

%% --- Convert to desired units ---
maxDeflection_mm = maxDeflection * 1000;    % mm
maxMoment_kNm    = maxMoment / 1e3;         % kN·m
maxShear_kN      = maxShear / 1e3;          % kN

%% --- Create table for UITable ---
Parameter = {'Maximum Deflection'; 'Maximum Moment'; 'Maximum Shear'};
Value     = {...
    sprintf('%.3f mm', maxDeflection_mm); ...
    sprintf('%.2f kN·m', maxMoment_kNm); ...
    sprintf('%.2f kN', maxShear_kN)};

resultsTable = table(Parameter, Value);

%% --- Display in UITable ---
app.UITable.Data = resultsTable;

%% --- Plot Deflection ---
plot(app.UIAxes, x, y, 'b', 'LineWidth',1.5);
xlabel(app.UIAxes,'x [m]');
ylabel(app.UIAxes,'Deflection [m]');
title(app.UIAxes,'Beam Deflection');
grid(app.UIAxes,'on');

%% --- Plot Moment ---
plot(app.UIAxes_2, x, M, 'r', 'LineWidth',1.5);
xlabel(app.UIAxes_2,'x [m]');
ylabel(app.UIAxes_2,'Moment [N·m]');
title(app.UIAxes_2,'Bending Moment');
grid(app.UIAxes_2,'on');

%% --- Plot Shear ---
plot(app.UIAxes_4, x, V, 'g', 'LineWidth',1.5);
xlabel(app.UIAxes_4,'x [m]');
ylabel(app.UIAxes_4,'Shear [N]');
title(app.UIAxes_4,'Shear Force');
grid(app.UIAxes_4,'on');
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.BeamLengthmLabel = uilabel(app.InputsPanel);
            app.BeamLengthmLabel.Text = {'Beam Length'; ' (m)'};
            app.BeamLengthmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.YoungsModulusPaEditFieldLabel = uilabel(app.InputsPanel);
            app.YoungsModulusPaEditFieldLabel.Text = {'Young''s Modulus'; ' [Pa]'};
            app.YoungsModulusPaEditField = uieditfield(app.InputsPanel, 'numeric');
            app.SecondMomentofAream4Label = uilabel(app.InputsPanel);
            app.SecondMomentofAream4Label.Text = {'Second Moment '; 'of Area '; '[m^4]'};
            app.SecondMomentofAream4EditField = uieditfield(app.InputsPanel, 'numeric');
            app.SupportTypeDropDownLabel = uilabel(app.InputsPanel);
            app.SupportTypeDropDownLabel.Text = 'Support Type';
            app.SupportTypeDropDown = uidropdown(app.InputsPanel);
            app.SupportTypeDropDown.Items = {'Cantilever', 'Simply', 'fixedfixed'};
            app.SupportTypeDropDown.Value = 'Cantilever';
            app.LoadTypeDropDownLabel = uilabel(app.InputsPanel);
            app.LoadTypeDropDownLabel.Text = 'Load Type';
            app.LoadTypeDropDown = uidropdown(app.InputsPanel);
            app.LoadTypeDropDown.Items = {'Point', 'UDL'};
            app.LoadTypeDropDown.Value = 'Point';
            app.PointLoadPNLabel = uilabel(app.InputsPanel);
            app.PointLoadPNLabel.Text = {'Point Load ''P'' '; '(N)'};
            app.PointLoadPNEditField = uieditfield(app.InputsPanel, 'numeric');
            app.PointLoadPositionamLabel = uilabel(app.InputsPanel);
            app.PointLoadPositionamLabel.Text = {'Point Load '; 'Position a '; '(m)'};
            app.PointLoadPositionamEditField = uieditfield(app.InputsPanel, 'numeric');
            app.UDLIntensitywNmLabel = uilabel(app.InputsPanel);
            app.UDLIntensitywNmLabel.Text = {'UDL Intensity w '; '(N/m)'};
            app.UDLIntensitywNmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.CalculateButton = uibutton(app.InputsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveResultsButton = uibutton(app.InputsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsPanel);
            title(app.UIAxes, 'Deflection Curve')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes_2, 'Bending Moment')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.ResultsPanel);
            title(app.UIAxes_4, 'Shear Force')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = {'Property'; 'Value'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Beam_Deflection_Calculator_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end